classdef app_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        YDropDown_2Label              matlab.ui.control.Label
        YDropDown_2                   matlab.ui.control.DropDown
        ZDropDown                     matlab.ui.control.DropDown
        ZDropDownLabel                matlab.ui.control.Label
        XDropDown                     matlab.ui.control.DropDown
        XDropDownLabel                matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        AccuracyEditField             matlab.ui.control.NumericEditField
        AccuracyEditFieldLabel        matlab.ui.control.Label
        PickaClassifierDropDown       matlab.ui.control.DropDown
        PickaClassifierDropDownLabel  matlab.ui.control.Label
        TrainModelButton              matlab.ui.control.Button
        TestModelButton               matlab.ui.control.Button
        LoadTrainDatasetButton        matlab.ui.control.Button
        LoadTestDatasetButton         matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data_Train
        Data_Test
        trainedClassifier
        validationAccuracy
        yfit
        scores
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadTrainDatasetButton
        function LoadTrainDatasetButtonPushed(app, event)
            load fisheriris.mat;
            
            X = meas(1:150);
            species_num = grp2idx(species);
            
            Y1 = species_num(1:50);
            Y2 = species_num(51:100);
            Y3 = species_num(101:150);
            
            X1 = meas(randperm(50),:);
            X2 = meas((51:100),:);
            X2 = X2(randperm(50),:);
            X3 = meas((101:150),:);
            X3 = X3(randperm(50),:);
            
            %train
            X_Train(1:45,:) = X1(1:45,:);
            X_Train(46:90,:) = X2(1:45,:);
            X_Train(91:135,:) = X3(1:45,:);
            
            Y_Train = Y1(1:45);
            Y_Train(46:90) = Y2(1:45);
            Y_Train(91:135) = Y3(1:45);
            
            %test
            X_Test(1:5,:) = X1(46:50,:);
            X_Test(6:10,:) = X2(46:50,:);
            X_Test(11:15,:) = X3(46:50,:);
            
            Y_Test = Y1(46:50);
            Y_Test(6:10) = Y2(46:50);
            Y_Test(11:15) = Y3(46:50);
            
            app.Data_Train = [X_Train, Y_Train];
            app.Data_Train = app.Data_Train(randperm(135),:);
            app.Data_Test = [X_Test, Y_Test];
            
        end

        % Button pushed function: LoadTestDatasetButton
        function LoadTestDatasetButtonPushed(app, event)
            load fisheriris.mat;

            species_num = grp2idx(species);
            X = meas(1:150);
            y = species_num(1:150);

            Y1 = species_num(1:50);
            Y2 = species_num(51:100);
            Y3 = species_num(101:150);
            
            X1 = meas(randperm(50),:);
            X2 = meas((51:100),:);
            X2 = X2(randperm(50),:);
            X3 = meas((101:150),:);
            X3 = X3(randperm(50),:);

             %test
            X_Test(1:5,:) = X1(46:50,:);
            X_Test(6:10,:) = X2(46:50,:);
            X_Test(11:15,:) = X3(46:50,:);
            
            Y_Test = Y1(46:50);
            Y_Test(6:10) = Y2(46:50);
            Y_Test(11:15) = Y3(46:50);

            app.Data_Test = [X_Test, Y_Test];
        end

        % Value changed function: PickaClassifierDropDown
        function PickaClassifierDropDownValueChanged(app, event)
            
        end

        % Button pushed function: TrainModelButton
        function TrainModelButtonPushed(app, event)
            if(app.PickaClassifierDropDown.Value == "KNN")
                [app.trainedClassifier, app.validationAccuracy] = trainClassifier_knn(app.Data_Train);
            end
            if(app.PickaClassifierDropDown.Value == "SVM")
                [app.trainedClassifier, app.validationAccuracy] = trainClassifier_svm(app.Data_Train);
            end
            
        end

        % Button pushed function: TestModelButton
        function TestModelButtonPushed(app, event)
            [app.yfit,app.scores] = app.trainedClassifier.predictFcn(app.Data_Test(:,1:4));
            a=0;
              for i = 1:5
               if (app.yfit(i) == 1)
                   a = a + 1;
               end
                if (app.yfit(i+5) == 2)
                   a = a + 1;
                end
                if (app.yfit(i+10) == 3)
                   a = a + 1;
                end
              end
              app.AccuracyEditField.Value= (a/15)*100;
                  
        end

        % Callback function: PlotButton, UIAxes
        function PlotButtonPushed(app, event)
            grid (app.UIAxes,"on");
            
            if(app.XDropDown.Value == "SepalLength")
               xa = app.Data_Test(:,1);
            end
            if (app.XDropDown.Value == "SepalWidth")
               xa = app.Data_Test(:,2);
            end
            if (app.XDropDown.Value == "PetalLength")
               xa = app.Data_Test(:,3);
            end
            if (app.XDropDown.Value == "PetalWidth")
               xa = app.Data_Test(:,4);
            end
            if(app.YDropDown_2.Value == "SepalLength")
               ya = app.Data_Test(:,1);
            end
            if (app.YDropDown_2.Value == "SepalWidth")
               ya = app.Data_Test(:,2);
            end
            if (app.YDropDown_2.Value == "PetalLength")
               ya = app.Data_Test(:,3);
            end
            if (app.YDropDown_2.Value == "PetalWidth")
               ya = app.Data_Test(:,4);
            end
             if(app.ZDropDown.Value == "SepalLength")
               za = app.Data_Test(:,1);
            end
            if (app.ZDropDown.Value == "SepalWidth")
               za = app.Data_Test(:,2);
            end
            if (app.ZDropDown.Value == "PetalLength")
               za = app.Data_Test(:,3);
            end
            if (app.ZDropDown.Value == "PetalWidth")
               za = app.Data_Test(:,4);
            end

           for i=1:15
           if(app.Data_Test(i,5) == 1)
           scatter3(app.UIAxes,xa(i),ya(i),za(i),"o","blue");
            elseif(app.Data_Test(i,5) == 2)
            scatter3(app.UIAxes,xa(i),ya(i),za(i),"o","red");
            
            elseif (app.Data_Test(i,5) == 3)
            scatter3(app.UIAxes,xa(i),ya(i),za(i),"o","green");
           end
           hold (app.UIAxes,"on");
  
             if(app.yfit(i) == 1) 
                 scatter3(app.UIAxes,xa(i),ya(i),za(i),'*',"blue");

             elseif(app.yfit(i) == 2)
                 scatter3(app.UIAxes,xa(i),ya(i),za(i),"*","red");

             elseif(app.yfit(i) == 3)
                 scatter3(app.UIAxes,xa(i),ya(i),za(i),"*","green");
             end
             hold (app.UIAxes,"on");
            end
             hold (app.UIAxes,"off");
         
           xlabel(app.UIAxes,app.XDropDown.Value);
           ylabel(app.UIAxes,app.YDropDown_2.Value);
           zlabel(app.UIAxes,app.ZDropDown.Value);
           legend(app.UIAxes,'True Labels','','','Perdicted Labels',Location="northwest");
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Species with True and Predicted Labels')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.LoadTestDatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadTestDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTestDatasetButtonPushed, true);
            app.LoadTestDatasetButton.Text = 'Load Test Dataset';
            app.LoadTrainDatasetButton = uibutton(app.UIFigure, 'push');
            app.LoadTrainDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadTrainDatasetButtonPushed, true);
            app.LoadTrainDatasetButton.Text = 'Load Train Dataset';
            app.TestModelButton = uibutton(app.UIFigure, 'push');
            app.TestModelButton.ButtonPushedFcn = createCallbackFcn(app, @TestModelButtonPushed, true);
            app.TestModelButton.Text = 'Test Model';
            app.TrainModelButton = uibutton(app.UIFigure, 'push');
            app.TrainModelButton.ButtonPushedFcn = createCallbackFcn(app, @TrainModelButtonPushed, true);
            app.TrainModelButton.Text = 'Train Model';
            app.PickaClassifierDropDownLabel = uilabel(app.UIFigure);
            app.PickaClassifierDropDownLabel.Text = 'Pick a Classifier';
            app.PickaClassifierDropDown = uidropdown(app.UIFigure);
            app.PickaClassifierDropDown.Items = {'KNN', 'SVM'};
            app.PickaClassifierDropDown.ValueChangedFcn = createCallbackFcn(app, @PickaClassifierDropDownValueChanged, true);
            app.PickaClassifierDropDown.Value = 'KNN';
            app.AccuracyEditFieldLabel = uilabel(app.UIFigure);
            app.AccuracyEditFieldLabel.Text = 'Accuracy';
            app.AccuracyEditField = uieditfield(app.UIFigure, 'numeric');
            app.AccuracyEditField.Value = 100;
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.XDropDownLabel = uilabel(app.UIFigure);
            app.XDropDownLabel.Text = 'X';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'SepalLength', 'SepalWidth', 'PetalLength', 'PetalWidth'};
            app.XDropDown.Value = 'SepalLength';
            app.ZDropDownLabel = uilabel(app.UIFigure);
            app.ZDropDownLabel.Text = 'Z';
            app.ZDropDown = uidropdown(app.UIFigure);
            app.ZDropDown.Items = {'SepalLength', 'SepalWidth', 'PetalLength', 'PetalWidth'};
            app.ZDropDown.Value = 'SepalLength';
            app.YDropDown_2 = uidropdown(app.UIFigure);
            app.YDropDown_2.Items = {'SepalLength', 'SepalWidth', 'PetalLength', 'PetalWidth'};
            app.YDropDown_2.Value = 'SepalLength';
            app.YDropDown_2Label = uilabel(app.UIFigure);
            app.YDropDown_2Label.Text = 'Y';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end